classdef AboutSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AboutUIFigure  matlab.ui.Figure
        Hyperlink      matlab.ui.control.Hyperlink
        VersionLabel   matlab.ui.control.Label
        Image          matlab.ui.control.Image
    end

    properties (Access = private)
        CallingApp  % Main app
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            
            % Update the version label
            app.VersionLabel.Text = sprintf('Version: %s',app.CallingApp.version);
            
            % Set the location of the window
            left = app.CallingApp.ContourAlignmentToolUIFigure.Position(1)+app.CallingApp.ContourAlignmentToolUIFigure.Position(3)/2-app.AboutUIFigure.Position(3)/2;
            bottom = app.CallingApp.ContourAlignmentToolUIFigure.Position(2)+app.CallingApp.ContourAlignmentToolUIFigure.Position(4)/2-app.AboutUIFigure.Position(4)/2;
            movegui(app.AboutUIFigure,[left bottom]);
        end

        % Close request function: AboutUIFigure
        function AboutUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AboutUIFigure = uifigure('Visible', 'off');
            app.AboutUIFigure.Name = 'About';
            app.AboutUIFigure.Icon = 'icon_48.png';
            app.AboutUIFigure.CloseRequestFcn = createCallbackFcn(app, @AboutUIFigureCloseRequest, true);
            app.Image = uiimage(app.AboutUIFigure);
            app.Image.ImageSource = 'about.png';
            app.VersionLabel = uilabel(app.AboutUIFigure);
            app.VersionLabel.Text = 'Version:';
            app.Hyperlink = uihyperlink(app.AboutUIFigure);
            app.Hyperlink.URL = 'https://github.com/ACRF-Image-X-Institute/contour-alignment-tool';
            app.Hyperlink.Text = 'GitHub Repository';
            % Show the figure after all components are created
            app.AboutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AboutSoftware(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AboutUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AboutUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AboutUIFigure)
        end
    end
end