classdef DRRviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        drrUIFigure              matlab.ui.Figure
        contrastROIButton        matlab.ui.control.StateButton
        contrastContourButton    matlab.ui.control.StateButton
        contrastAutoButton       matlab.ui.control.StateButton
        contrastManualButton     matlab.ui.control.StateButton
        GridLayout               matlab.ui.container.GridLayout
        ContrastAdjustmentPanel  matlab.ui.container.Panel
        UpperSlider              matlab.ui.control.Slider
        LowerSlider              matlab.ui.control.Slider
        Histogram                matlab.ui.control.UIAxes
        ROIselectionLabel        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp  % Main app
        displaySize
        checkers
        I
        ROIrange
    end
    
    methods (Access = public)
        
        % Update the DRR viewer
        function updateDRR(app)

            if strcmp(app.CallingApp.fileType,'.his')
                drrMask = imrotate(app.CallingApp.originalMasks(:,:,app.CallingApp.currentFrame),-90);
            else
                drrMask = permute(app.CallingApp.originalMasks(:,:,app.CallingApp.currentFrame),[2 1 3]); 
            end
            
            
            drrMask = imbinarize(drrMask,0);
            drrMaskOutline = boundarymask(drrMask);
            
            app.I = app.CallingApp.DRR;
                
            % Set the contrast of the projection
            if app.contrastAutoButton.Value || app.contrastManualButton.Value
            
                lower = prctile(app.I(:),0.1);
                upper = prctile(app.I(:),99);
                if app.contrastAutoButton.Value
                    app.LowerSlider.Value = (prctile(app.I(:),5)-lower)/(upper-lower);
                    app.UpperSlider.Value = (prctile(app.I(:),95)-lower)/(upper-lower);
                end

            elseif app.contrastROIButton.Value
               lower = app.ROIrange(1);
               upper = app.ROIrange(2);
               
            elseif app.contrastContourButton.Value
                s = regionprops(double(drrMask),'BoundingBox');
                s.BoundingBox = round(s.BoundingBox);
                
                windowsize = 100;
                windowposition(1) = s.BoundingBox(2)-windowsize/2;
                if windowposition(1) < 1
                    windowposition(1) = 1;
                end
                
                windowposition(2) = s.BoundingBox(2)+s.BoundingBox(4)+windowsize/2;
                if windowposition(2) > app.CallingApp.imageSize(1)
                    windowposition(2) = app.CallingApp.imageSize(1);
                end
                
                windowposition(3) = s.BoundingBox(1)-windowsize/2;
                if windowposition(3) < 1
                    windowposition(3) = 1;
                end
                
                windowposition(4) =  s.BoundingBox(1)+s.BoundingBox(3)+windowsize/2;
                if windowposition(4) > app.CallingApp.imageSize(2)
                    windowposition(4) = app.CallingApp.imageSize(2);
                end

                lower = min(min(app.I(windowposition(1):windowposition(2),...
                    windowposition(3):windowposition(4))));
                upper = max(max(app.I(windowposition(1):windowposition(2),...
                    windowposition(3):windowposition(4))));                   
                        
            end
            
            val1 = app.LowerSlider.Value * (upper-lower)+lower;
            val2 = app.UpperSlider.Value * (upper-lower)+lower;
            histogram(app.Histogram,app.I,100, 'BinLimits',[lower,upper],'EdgeColor','none','FaceColor',[0.65,0.65,0.65]); 
            
                
            % Set the width of the DRR display
            if app.UIAxes.Position(3)/app.UIAxes.Position(4) <= app.CallingApp.imageSize(2)/app.CallingApp.imageSize(1)
                app.displaySize(1) = app.UIAxes.Position(3);
                app.displaySize(2) = app.UIAxes.Position(3)/(app.CallingApp.imageSize(2)/app.CallingApp.imageSize(1));
            else
                app.displaySize(1) = app.UIAxes.Position(4)*(app.CallingApp.imageSize(2)/app.CallingApp.imageSize(1));
                app.displaySize(2) = app.UIAxes.Position(4);
            end                       
            
                
            % Display the DRR
            imshow(app.I,[min([val1,val2])*max(app.I(:)) max([val1,val2])*max(app.I(:))],'Parent',app.UIAxes,'XData', [1 app.displaySize(1)],'YData', [1 app.displaySize(2)]);
            app.UIAxes.XLim = [1 app.displaySize(1)];
            app.UIAxes.YLim = [1 app.displaySize(2)];
            
            % Display the original contour
            hold(app.UIAxes,'on')
                maskimage = imshow(app.checkers,'Parent',app.UIAxes,'XData', [1 app.displaySize(1)],'YData', [1 app.displaySize(2)]);
            hold(app.UIAxes,'off')
            set(maskimage, 'AlphaData', drrMaskOutline) 
            
            % Display the current contour
            hold(app.UIAxes,'on')
                maskimage = imshow(app.CallingApp.colour,'Parent',app.UIAxes,'XData', [1 app.displaySize(1)],'YData', [1 app.displaySize(2)]);
            hold(app.UIAxes,'off')
            if app.CallingApp.ContourFillMenu.Checked
                set(maskimage, 'AlphaData', app.CallingApp.Mask*0.25) 
            else
                set(maskimage, 'AlphaData', app.CallingApp.MaskOutline) 
            end

            % Show the margin.
            if app.CallingApp.mode == "selection"
                hold(app.UIAxes, 'on');
                [h, w, ~] = size(app.I);
                % Map margin from pixels to display.
                marginX = app.CallingApp.marginPx * app.displaySize(1) / w;
                marginY = app.CallingApp.marginPx * app.displaySize(2) / h;
                rectangle(app.UIAxes, ...
                    'Position', [marginX, marginY, app.displaySize(1) - 2 * marginX, app.displaySize(2) - 2 * marginY], ...
                    'EdgeColor', 'yellow', ...
                    'LineWidth', 1 );
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            
            % Set the location of the window
            left = app.CallingApp.ContourAlignmentToolUIFigure.Position(1)+app.CallingApp.ContourAlignmentToolUIFigure.Position(3)/2-app.drrUIFigure.Position(3)/2;
            bottom = app.CallingApp.ContourAlignmentToolUIFigure.Position(2)+app.CallingApp.ContourAlignmentToolUIFigure.Position(4)/2-app.drrUIFigure.Position(4)/2;
            movegui(app.drrUIFigure,[left bottom]);
            
            % Disable interactivity
            disableDefaultInteractivity(app.UIAxes)
            disableDefaultInteractivity(app.Histogram)
            
            % Create the image used for the original contour position
            % border
            app.checkers = checkerboard(4,app.CallingApp.imageSize(1)/8,app.CallingApp.imageSize(2)/8)>0;
            app.checkers = double(cat(3,app.checkers,app.checkers,app.checkers)).*cat(3, ones(app.CallingApp.imageSize(1),app.CallingApp.imageSize(2)), zeros(app.CallingApp.imageSize(1),app.CallingApp.imageSize(2)), zeros(app.CallingApp.imageSize(1),app.CallingApp.imageSize(2)));
        end

        % Close request function: drrUIFigure
        function drrUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: contrastAutoButton, 
        % ...and 3 other components
        function ContrastAdjustment(app, event)
            app.contrastManualButton.Value = 0;
            app.contrastAutoButton.Value = 0;
            app.contrastContourButton.Value = 0;
            app.contrastROIButton.Value = 0;
            
            switch event.Source.Text
                
                case 'Manual'
                    app.contrastManualButton.Value = 1;
                    app.LowerSlider.Value = 0;
                    app.UpperSlider.Value = 1;
                    
                case 'Auto'
                    app.contrastAutoButton.Value = 1;
                    
                case 'Contour'
                    app.contrastContourButton.Value = 1;
                    app.LowerSlider.Value = 0;
                    app.UpperSlider.Value = 1;
                    
                case 'ROI'
                    app.contrastROIButton.Value = 1;
                    app.ROIselectionLabel.Visible = 'on';
                    
                    ROI = drawrectangle(app.UIAxes);
                    ROI.Position(1) = round(ROI.Position(1)/app.displaySize(1)*app.CallingApp.imageSize(2));
                    ROI.Position(3) = round(ROI.Position(3)/app.displaySize(1)*app.CallingApp.imageSize(2));
                    ROI.Position(2) = round(ROI.Position(2)/app.displaySize(2)*app.CallingApp.imageSize(1));
                    ROI.Position(4) = round(ROI.Position(4)/app.displaySize(2)*app.CallingApp.imageSize(1));
                     
                    
                    app.LowerSlider.Value = 0;
                    app.UpperSlider.Value = 1;
                    
                    
                    app.ROIrange(1) = min(min(app.I(ROI.Position(2):ROI.Position(2)+ROI.Position(4),...
                        ROI.Position(1):ROI.Position(1)+ROI.Position(3))));
                    app.ROIrange(2) = max(max(app.I(ROI.Position(2):ROI.Position(2)+ROI.Position(4),...
                        ROI.Position(1):ROI.Position(1)+ROI.Position(3))));
                    
                    delete(ROI)
                    
                    app.ROIselectionLabel.Visible = 'off';
            end
            
            
            updateDRR(app)  
        end

        % Value changed function: LowerSlider, UpperSlider
        function SliderValueChanging(app, event)
            % Update the contrast if the slider is changed
             if app.contrastAutoButton.Value
                app.contrastManualButton.Value = 1;
                app.contrastAutoButton.Value = 0;
             end
            updateDRR(app)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.drrUIFigure = uifigure('Visible', 'off');
            app.drrUIFigure.Name = 'DRR Viewer';
            app.drrUIFigure.Icon = 'icon_48.png';
            app.drrUIFigure.CloseRequestFcn = createCallbackFcn(app, @drrUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.drrUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.ROIselectionLabel = uilabel(app.GridLayout);
            app.ROIselectionLabel.Visible = 'off';
            app.ROIselectionLabel.Text = 'Click and drag the mouse to draw the ROI for auto-contrast adjustment ';
            app.ContrastAdjustmentPanel = uipanel(app.GridLayout);
            app.ContrastAdjustmentPanel.Title = 'Contrast Adjustment';
            app.Histogram = uiaxes(app.ContrastAdjustmentPanel);
            app.Histogram.XTick = [];
            app.Histogram.XTickLabel = '';
            app.Histogram.YTick = [];
            app.Histogram.YTickLabel = '';
            app.Histogram.Visible = 'off';
            app.LowerSlider = uislider(app.ContrastAdjustmentPanel);
            app.LowerSlider.Limits = [0 1];
            app.LowerSlider.MajorTicks = [];
            app.LowerSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.UpperSlider = uislider(app.ContrastAdjustmentPanel);
            app.UpperSlider.Limits = [0 1];
            app.UpperSlider.MajorTicks = [];
            app.UpperSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.UpperSlider.Value = 1;
            app.contrastManualButton = uibutton(app.drrUIFigure, 'state');
            app.contrastManualButton.ValueChangedFcn = createCallbackFcn(app, @ContrastAdjustment, true);
            app.contrastManualButton.Tooltip = {'Manual contrast adjustment'};
            app.contrastManualButton.Icon = 'manual.png';
            app.contrastManualButton.Text = 'Manual';
            app.contrastAutoButton = uibutton(app.drrUIFigure, 'state');
            app.contrastAutoButton.ValueChangedFcn = createCallbackFcn(app, @ContrastAdjustment, true);
            app.contrastAutoButton.Tooltip = {'Auto contrast adjustment'};
            app.contrastAutoButton.Icon = 'auto.png';
            app.contrastAutoButton.Text = 'Auto';
            app.contrastAutoButton.Value = true;
            app.contrastContourButton = uibutton(app.drrUIFigure, 'state');
            app.contrastContourButton.ValueChangedFcn = createCallbackFcn(app, @ContrastAdjustment, true);
            app.contrastContourButton.Tooltip = {'Contrast based on a region around the contour'};
            app.contrastContourButton.Icon = 'contour.png';
            app.contrastContourButton.Text = 'Contour';
            app.contrastROIButton = uibutton(app.drrUIFigure, 'state');
            app.contrastROIButton.ValueChangedFcn = createCallbackFcn(app, @ContrastAdjustment, true);
            app.contrastROIButton.Tooltip = {'Contrast based on a selected region of interest'};
            app.contrastROIButton.Icon = 'ROI.png';
            app.contrastROIButton.Text = 'ROI';
            % Show the figure after all components are created
            app.drrUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DRRviewer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.drrUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.drrUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.drrUIFigure)
        end
    end
end