classdef Manual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManualUIFigure       matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        DataLoadingTab       matlab.ui.container.Tab
        Image6               matlab.ui.control.Image
        ContourAlignmentTab  matlab.ui.container.Tab
        Image5               matlab.ui.control.Image
        DisplayOptionsTab    matlab.ui.container.Tab
        Image2               matlab.ui.control.Image
        DataSavingTab        matlab.ui.container.Tab
        Image4               matlab.ui.control.Image
        TroubleshootingTab   matlab.ui.container.Tab
        ReportanissueButton  matlab.ui.control.Button
        Image3               matlab.ui.control.Image
    end

    
    properties (Access = private)
        CallingApp  % Main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            
            % Set the location of the window
            left = app.CallingApp.ContourAlignmentToolUIFigure.Position(1)+app.CallingApp.ContourAlignmentToolUIFigure.Position(3)/2-app.ManualUIFigure.Position(3)/2;
            bottom = app.CallingApp.ContourAlignmentToolUIFigure.Position(2)+app.CallingApp.ContourAlignmentToolUIFigure.Position(4)/2-app.ManualUIFigure.Position(4)/2;
            movegui(app.ManualUIFigure,[left bottom]);
        end

        % Close request function: ManualUIFigure
        function ManualUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: ReportanissueButton
        function ReportanissueButtonPushed(app, event)
             % Generate a draft email for reporting an issue
            web(sprintf('mailto:adam.mylonas@sydney.edu.au?subject=Issue with Contour Alignment Tool version %s',app.CallingApp.version))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManualUIFigure = uifigure('Visible', 'off');
            app.ManualUIFigure.Name = 'Manual';
            app.ManualUIFigure.Icon = 'icon_48.png';
            app.ManualUIFigure.CloseRequestFcn = createCallbackFcn(app, @ManualUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.ManualUIFigure);
            app.DataLoadingTab = uitab(app.TabGroup);
            app.DataLoadingTab.Title = 'Data Loading';
            app.Image6 = uiimage(app.DataLoadingTab);
            app.Image6.ImageSource = 'instructions-loading.png';
            app.ContourAlignmentTab = uitab(app.TabGroup);
            app.ContourAlignmentTab.Title = 'Contour Alignment';
            app.Image5 = uiimage(app.ContourAlignmentTab);
            app.Image5.ImageSource = 'instructions-alignment.png';
            app.DisplayOptionsTab = uitab(app.TabGroup);
            app.DisplayOptionsTab.Title = 'Display Options';
            app.Image2 = uiimage(app.DisplayOptionsTab);
            app.Image2.ImageSource = 'instructions-display.png';
            app.DataSavingTab = uitab(app.TabGroup);
            app.DataSavingTab.Title = 'Data Saving';
            app.Image4 = uiimage(app.DataSavingTab);
            app.Image4.ImageSource = 'instructions-saving.png';
            app.TroubleshootingTab = uitab(app.TabGroup);
            app.TroubleshootingTab.Title = 'Troubleshooting';
            app.Image3 = uiimage(app.TroubleshootingTab);
            app.Image3.ImageSource = 'instructions-issue.png';
            app.ReportanissueButton = uibutton(app.TroubleshootingTab, 'push');
            app.ReportanissueButton.ButtonPushedFcn = createCallbackFcn(app, @ReportanissueButtonPushed, true);
            app.ReportanissueButton.Text = 'Report an issue';
            % Show the figure after all components are created
            app.ManualUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Manual(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ManualUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ManualUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManualUIFigure)
        end
    end
end